classdef IHMRoboDidatico_V0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RobotHMIUIFigure              matlab.ui.Figure
        RunMenu                       matlab.ui.container.Menu
        LoadTrajectoryMenu            matlab.ui.container.Menu
        MoveRobotintoPositionMenu     matlab.ui.container.Menu
        ProgramMenu                   matlab.ui.container.Menu
        SimpleTrajectoryMenu          matlab.ui.container.Menu
        MoveMenu_2                    matlab.ui.container.Menu
        WaypointMenu                  matlab.ui.container.Menu
        SetMenu                       matlab.ui.container.Menu
        WaitMenu                      matlab.ui.container.Menu
        PopupMenu                     matlab.ui.container.Menu
        HaltMenu                      matlab.ui.container.Menu
        AdvancedTrajectoryMenu        matlab.ui.container.Menu
        NewScriptMenu                 matlab.ui.container.Menu
        LoadScriptMenu                matlab.ui.container.Menu
        TemplatesMenu                 matlab.ui.container.Menu
        SeekMenu                      matlab.ui.container.Menu
        ForceMenu                     matlab.ui.container.Menu
        PalletizingMenu               matlab.ui.container.Menu
        ConveyorTrackingMenu          matlab.ui.container.Menu
        RemoteToolMenu                matlab.ui.container.Menu
        RemoteToolCPMoveMenu          matlab.ui.container.Menu
        InstallationMenu              matlab.ui.container.Menu
        GeneralMenu                   matlab.ui.container.Menu
        ToolCPMenu                    matlab.ui.container.Menu
        MountingMenu                  matlab.ui.container.Menu
        IOSetupMenu                   matlab.ui.container.Menu
        StartupMenu                   matlab.ui.container.Menu
        HomeMenu                      matlab.ui.container.Menu
        SafetyMenu                    matlab.ui.container.Menu
        RobotLimitsMenu               matlab.ui.container.Menu
        JointLimitsMenu               matlab.ui.container.Menu
        PlanesMenu                    matlab.ui.container.Menu
        ToolPositionMenu              matlab.ui.container.Menu
        ToolDirectionMenu             matlab.ui.container.Menu
        IOMenu_2                      matlab.ui.container.Menu
        HardwareMenu                  matlab.ui.container.Menu
        ThreePositionMenu             matlab.ui.container.Menu
        SafeHomeMenu                  matlab.ui.container.Menu
        FeaturesMenu                  matlab.ui.container.Menu
        PointMenu                     matlab.ui.container.Menu
        LineMenu                      matlab.ui.container.Menu
        PlaneMenu                     matlab.ui.container.Menu
        FieldbusMenu                  matlab.ui.container.Menu
        MoveMenu                      matlab.ui.container.Menu
        IOMenu                        matlab.ui.container.Menu
        LogMenu                       matlab.ui.container.Menu
        ReadingsMenu                  matlab.ui.container.Menu
        JointLoadMenu                 matlab.ui.container.Menu
        DateLogMenu                   matlab.ui.container.Menu
        PlotsMenu                     matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        PreferencesMenu               matlab.ui.container.Menu
        PasswordMenu                  matlab.ui.container.Menu
        SystemMenu                    matlab.ui.container.Menu
        RobotRegistrationMenu         matlab.ui.container.Menu
        Joint1DegreeAngle             matlab.ui.control.NumericEditField
        Joint2DegreeAngle             matlab.ui.control.NumericEditField
        Joint5DegreeAngle             matlab.ui.control.NumericEditField
        Joint4DegreeAngle             matlab.ui.control.NumericEditField
        Joint3DegreeAngle             matlab.ui.control.NumericEditField
        Joint1Label                   matlab.ui.control.Label
        Joint2Label                   matlab.ui.control.Label
        Joint3Label                   matlab.ui.control.Label
        Joint4Label                   matlab.ui.control.Label
        Joint5Label                   matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
        SliderJoint1                  matlab.ui.control.Slider
        SliderJoint2                  matlab.ui.control.Slider
        SliderJoint3                  matlab.ui.control.Slider
        SliderJoint4                  matlab.ui.control.Slider
        SliderJoint5                  matlab.ui.control.Slider
        T05                           matlab.ui.control.Table
        TransformationMatrixT05Label  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        RightArrowSlider1             matlab.ui.control.Button
        RightArrowSlider2             matlab.ui.control.Button
        RightArrowSlider3             matlab.ui.control.Button
        RightArrowSlider4             matlab.ui.control.Button
        RightArrowSlider5             matlab.ui.control.Button
        LeftArrowSlider1              matlab.ui.control.Button
        LeftArrowSlider2              matlab.ui.control.Button
        LeftArrowSlider3              matlab.ui.control.Button
        LeftArrowSlider4              matlab.ui.control.Button
        LeftArrowSlider5              matlab.ui.control.Button
    end

  
    methods (Access = private)
        
        function plot_transformation_matrix(app)
            th1 = app.SliderJoint1.Value;
            th2 = app.SliderJoint2.Value;
            th3 = app.SliderJoint3.Value;
            th4 = app.SliderJoint4.Value;
            th5 = app.SliderJoint5.Value;
            
            T05 = cin_direta([th1, th2, th3, th4, th5]);
            
            app.T05.Data = T05;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeRobotParameters();
        end

        % Value changing function: SliderJoint1
        function SliderJoint1ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint1DegreeAngle.Value = round(changingValue);
        end

        % Value changing function: SliderJoint2
        function SliderJoint2ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint2DegreeAngle.Value = round(changingValue);
        end

        % Value changing function: SliderJoint3
        function SliderJoint3ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint3DegreeAngle.Value = round(changingValue);
        end

        % Value changing function: SliderJoint4
        function SliderJoint4ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint4DegreeAngle.Value = round(changingValue);
        end

        % Value changing function: SliderJoint5
        function SliderJoint5ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint5DegreeAngle.Value = round(changingValue);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            % Set robot to home position
            
            % Update numeric fields
            app.Joint1DegreeAngle.Value = 0;
            app.Joint2DegreeAngle.Value = 0;
            app.Joint3DegreeAngle.Value = 0;
            app.Joint4DegreeAngle.Value = 0;
            app.Joint5DegreeAngle.Value = 0;
            
            % Update sliders
            app.SliderJoint1.Value = 0;
            app.SliderJoint2.Value = 0;
            app.SliderJoint3.Value = 0;
            app.SliderJoint4.Value = 0;
            app.SliderJoint5.Value = 0;
            
            plot_transformation_matrix(app);
        end

        % Value changed function: SliderJoint1
        function SliderJoint1ValueChanged(app, event)
            plot_transformation_matrix(app);
        end

        % Value changed function: SliderJoint2
        function SliderJoint2ValueChanged(app, event)
            plot_transformation_matrix(app);
        end

        % Value changed function: SliderJoint3
        function SliderJoint3ValueChanged(app, event)
            plot_transformation_matrix(app);        
        end

        % Value changed function: SliderJoint4
        function SliderJoint4ValueChanged(app, event)
            plot_transformation_matrix(app);
        end

        % Value changed function: SliderJoint5
        function SliderJoint5ValueChanged(app, event)
            plot_transformation_matrix(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            [Thr, Thd] = plot_angular_positions([0,0,0,0,0], [90,0,0,0,0]);
            plot(app.UIAxes, Thr(:,1), 'r')
            hold(app.UIAxes)
            plot(app.UIAxes, Thd(:,1), 'b')
        end

        % Button pushed function: RightArrowSlider1
        function RightArrowSlider1ButtonPushed(app, event)
            app.SliderJoint1.Value = app.SliderJoint1.Value + 1;
            app.Joint1DegreeAngle.Value = app.SliderJoint1.Value;
            plot_transformation_matrix(app);
        end

        % Button pushed function: RightArrowSlider2
        function RightArrowSlider2ButtonPushed(app, event)
            app.SliderJoint2.Value = app.SliderJoint2.Value + 1;
            app.Joint2DegreeAngle.Value = app.SliderJoint2.Value;
            plot_transformation_matrix(app);
        end

        % Button pushed function: RightArrowSlider3
        function RightArrowSlider3ButtonPushed(app, event)
            app.SliderJoint3.Value = app.SliderJoint3.Value + 1;
            app.Joint3DegreeAngle.Value = app.SliderJoint3.Value;
            plot_transformation_matrix(app);
        end

        % Button pushed function: RightArrowSlider4
        function RightArrowSlider4ButtonPushed(app, event)
            app.SliderJoint4.Value = app.SliderJoint4.Value + 1;
            app.Joint4DegreeAngle.Value = app.SliderJoint4.Value;
            plot_transformation_matrix(app);
        end

        % Button pushed function: RightArrowSlider5
        function RightArrowSlider5ButtonPushed(app, event)
            app.SliderJoint5.Value = app.SliderJoint5.Value + 1;
            app.Joint5DegreeAngle.Value = app.SliderJoint5.Value;
            plot_transformation_matrix(app);
        end

        % Button pushed function: LeftArrowSlider1
        function LeftArrowSlider1ButtonPushed(app, event)
            app.SliderJoint1.Value = app.SliderJoint1.Value - 1;
            app.Joint1DegreeAngle.Value = app.SliderJoint1.Value;
            plot_transformation_matrix(app);
        end

        % Button pushed function: LeftArrowSlider2
        function LeftArrowSlider2ButtonPushed(app, event)
            app.SliderJoint2.Value = app.SliderJoint2.Value - 1;
            app.Joint2DegreeAngle.Value = app.SliderJoint2.Value;
            plot_transformation_matrix(app);
        end

        % Button pushed function: LeftArrowSlider3
        function LeftArrowSlider3ButtonPushed(app, event)
            app.SliderJoint3.Value = app.SliderJoint3.Value - 1;
            app.Joint3DegreeAngle.Value = app.SliderJoint3.Value;
            plot_transformation_matrix(app);
        end

        % Button pushed function: LeftArrowSlider4
        function LeftArrowSlider4ButtonPushed(app, event)
            app.SliderJoint4.Value = app.SliderJoint4.Value - 1;
            app.Joint4DegreeAngle.Value = app.SliderJoint4.Value;
            plot_transformation_matrix(app);
        end

        % Button pushed function: LeftArrowSlider5
        function LeftArrowSlider5ButtonPushed(app, event)
            app.SliderJoint5.Value = app.SliderJoint5.Value - 1;
            app.Joint5DegreeAngle.Value = app.SliderJoint5.Value;
            plot_transformation_matrix(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RobotHMIUIFigure = uifigure('Visible', 'off');
            app.RobotHMIUIFigure.Name = 'Didactic Robot HMI V0.0';
            app.RunMenu = uimenu(app.RobotHMIUIFigure);
            app.RunMenu.Text = 'Run';
            app.LoadTrajectoryMenu = uimenu(app.RunMenu);
            app.LoadTrajectoryMenu.Text = 'Load Trajectory';
            app.MoveRobotintoPositionMenu = uimenu(app.RunMenu);
            app.MoveRobotintoPositionMenu.Text = 'Move Robot into Position';
            app.ProgramMenu = uimenu(app.RobotHMIUIFigure);
            app.ProgramMenu.Text = 'Program';
            app.SimpleTrajectoryMenu = uimenu(app.ProgramMenu);
            app.SimpleTrajectoryMenu.Text = 'Simple Trajectory';
            app.MoveMenu_2 = uimenu(app.SimpleTrajectoryMenu);
            app.MoveMenu_2.Text = 'Move';
            app.WaypointMenu = uimenu(app.SimpleTrajectoryMenu);
            app.WaypointMenu.Text = 'Waypoint';
            app.SetMenu = uimenu(app.SimpleTrajectoryMenu);
            app.SetMenu.Text = 'Set';
            app.WaitMenu = uimenu(app.SimpleTrajectoryMenu);
            app.WaitMenu.Text = 'Wait';
            app.PopupMenu = uimenu(app.SimpleTrajectoryMenu);
            app.PopupMenu.Text = 'Popup';
            app.HaltMenu = uimenu(app.SimpleTrajectoryMenu);
            app.HaltMenu.Text = 'Halt';
            app.AdvancedTrajectoryMenu = uimenu(app.ProgramMenu);
            app.AdvancedTrajectoryMenu.Text = 'Advanced Trajectory';
            app.NewScriptMenu = uimenu(app.AdvancedTrajectoryMenu);
            app.NewScriptMenu.Text = 'New Script';
            app.LoadScriptMenu = uimenu(app.AdvancedTrajectoryMenu);
            app.LoadScriptMenu.Text = 'Load Script';
            app.TemplatesMenu = uimenu(app.ProgramMenu);
            app.TemplatesMenu.Text = 'Templates';
            app.SeekMenu = uimenu(app.TemplatesMenu);
            app.SeekMenu.Text = 'Seek';
            app.ForceMenu = uimenu(app.TemplatesMenu);
            app.ForceMenu.Text = 'Force';
            app.PalletizingMenu = uimenu(app.TemplatesMenu);
            app.PalletizingMenu.Text = 'Palletizing';
            app.ConveyorTrackingMenu = uimenu(app.TemplatesMenu);
            app.ConveyorTrackingMenu.Text = 'Conveyor Tracking';
            app.RemoteToolMenu = uimenu(app.ProgramMenu);
            app.RemoteToolMenu.Text = 'Remote Tool';
            app.RemoteToolCPMoveMenu = uimenu(app.RemoteToolMenu);
            app.RemoteToolCPMoveMenu.Text = 'Remote Tool CP Move';
            app.InstallationMenu = uimenu(app.RobotHMIUIFigure);
            app.InstallationMenu.Text = 'Installation';
            app.GeneralMenu = uimenu(app.InstallationMenu);
            app.GeneralMenu.Text = 'General';
            app.ToolCPMenu = uimenu(app.GeneralMenu);
            app.ToolCPMenu.Text = 'Tool CP';
            app.MountingMenu = uimenu(app.GeneralMenu);
            app.MountingMenu.Text = 'Mounting';
            app.IOSetupMenu = uimenu(app.GeneralMenu);
            app.IOSetupMenu.Text = 'I/O Setup';
            app.StartupMenu = uimenu(app.GeneralMenu);
            app.StartupMenu.Text = 'Startup';
            app.HomeMenu = uimenu(app.GeneralMenu);
            app.HomeMenu.Text = 'Home';
            app.SafetyMenu = uimenu(app.InstallationMenu);
            app.SafetyMenu.Text = 'Safety';
            app.RobotLimitsMenu = uimenu(app.SafetyMenu);
            app.RobotLimitsMenu.Text = 'Robot Limits';
            app.JointLimitsMenu = uimenu(app.SafetyMenu);
            app.JointLimitsMenu.Text = 'Joint Limits';
            app.PlanesMenu = uimenu(app.SafetyMenu);
            app.PlanesMenu.Text = 'Planes';
            app.ToolPositionMenu = uimenu(app.SafetyMenu);
            app.ToolPositionMenu.Text = 'Tool Position';
            app.ToolDirectionMenu = uimenu(app.SafetyMenu);
            app.ToolDirectionMenu.Text = 'Tool Direction';
            app.IOMenu_2 = uimenu(app.SafetyMenu);
            app.IOMenu_2.Text = 'I/O';
            app.HardwareMenu = uimenu(app.SafetyMenu);
            app.HardwareMenu.Text = 'Hardware';
            app.ThreePositionMenu = uimenu(app.SafetyMenu);
            app.ThreePositionMenu.Text = 'Three Position';
            app.SafeHomeMenu = uimenu(app.SafetyMenu);
            app.SafeHomeMenu.Text = 'Safe Home';
            app.FeaturesMenu = uimenu(app.InstallationMenu);
            app.FeaturesMenu.Text = 'Features';
            app.PointMenu = uimenu(app.FeaturesMenu);
            app.PointMenu.Text = 'Point';
            app.LineMenu = uimenu(app.FeaturesMenu);
            app.LineMenu.Text = 'Line';
            app.PlaneMenu = uimenu(app.FeaturesMenu);
            app.PlaneMenu.Text = 'Plane';
            app.FieldbusMenu = uimenu(app.InstallationMenu);
            app.FieldbusMenu.Text = 'Fieldbus';
            app.MoveMenu = uimenu(app.RobotHMIUIFigure);
            app.MoveMenu.Text = 'Move';
            app.IOMenu = uimenu(app.RobotHMIUIFigure);
            app.IOMenu.Text = 'I/O';
            app.LogMenu = uimenu(app.RobotHMIUIFigure);
            app.LogMenu.Text = 'Log';
            app.ReadingsMenu = uimenu(app.LogMenu);
            app.ReadingsMenu.Text = 'Readings';
            app.JointLoadMenu = uimenu(app.LogMenu);
            app.JointLoadMenu.Text = 'Joint Load';
            app.DateLogMenu = uimenu(app.LogMenu);
            app.DateLogMenu.Text = 'Date Log';
            app.PlotsMenu = uimenu(app.LogMenu);
            app.PlotsMenu.Text = 'Plots';
            app.SettingsMenu = uimenu(app.RobotHMIUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.PreferencesMenu = uimenu(app.SettingsMenu);
            app.PreferencesMenu.Text = 'Preferences';
            app.PasswordMenu = uimenu(app.SettingsMenu);
            app.PasswordMenu.Text = 'Password';
            app.SystemMenu = uimenu(app.SettingsMenu);
            app.SystemMenu.Text = 'System';
            app.RobotRegistrationMenu = uimenu(app.SystemMenu);
            app.RobotRegistrationMenu.Text = 'Robot Registration';
            app.Joint1DegreeAngle = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.Joint2DegreeAngle = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.Joint5DegreeAngle = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.Joint4DegreeAngle = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.Joint3DegreeAngle = uieditfield(app.RobotHMIUIFigure, 'numeric');
            app.Joint1Label = uilabel(app.RobotHMIUIFigure);
            app.Joint1Label.Text = 'Joint 1';
            app.Joint2Label = uilabel(app.RobotHMIUIFigure);
            app.Joint2Label.Text = 'Joint 2';
            app.Joint3Label = uilabel(app.RobotHMIUIFigure);
            app.Joint3Label.Text = 'Joint 3';
            app.Joint4Label = uilabel(app.RobotHMIUIFigure);
            app.Joint4Label.Text = 'Joint 4';
            app.Joint5Label = uilabel(app.RobotHMIUIFigure);
            app.Joint5Label.Text = 'Joint 5';
            app.HomeButton = uibutton(app.RobotHMIUIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.OKButton = uibutton(app.RobotHMIUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SliderJoint1 = uislider(app.RobotHMIUIFigure);
            app.SliderJoint1.Limits = [-180 180];
            app.SliderJoint1.ValueChangedFcn = createCallbackFcn(app, @SliderJoint1ValueChanged, true);
            app.SliderJoint1.ValueChangingFcn = createCallbackFcn(app, @SliderJoint1ValueChanging, true);
            app.SliderJoint2 = uislider(app.RobotHMIUIFigure);
            app.SliderJoint2.Limits = [-180 180];
            app.SliderJoint2.ValueChangedFcn = createCallbackFcn(app, @SliderJoint2ValueChanged, true);
            app.SliderJoint2.ValueChangingFcn = createCallbackFcn(app, @SliderJoint2ValueChanging, true);
            app.SliderJoint3 = uislider(app.RobotHMIUIFigure);
            app.SliderJoint3.Limits = [-180 180];
            app.SliderJoint3.ValueChangedFcn = createCallbackFcn(app, @SliderJoint3ValueChanged, true);
            app.SliderJoint3.ValueChangingFcn = createCallbackFcn(app, @SliderJoint3ValueChanging, true);
            app.SliderJoint4 = uislider(app.RobotHMIUIFigure);
            app.SliderJoint4.Limits = [-180 180];
            app.SliderJoint4.ValueChangedFcn = createCallbackFcn(app, @SliderJoint4ValueChanged, true);
            app.SliderJoint4.ValueChangingFcn = createCallbackFcn(app, @SliderJoint4ValueChanging, true);
            app.SliderJoint5 = uislider(app.RobotHMIUIFigure);
            app.SliderJoint5.Limits = [-180 180];
            app.SliderJoint5.ValueChangedFcn = createCallbackFcn(app, @SliderJoint5ValueChanged, true);
            app.SliderJoint5.ValueChangingFcn = createCallbackFcn(app, @SliderJoint5ValueChanging, true);
            app.T05 = uitable(app.RobotHMIUIFigure);
            app.T05.ColumnName = {'A'; 'B'; 'C'; 'D'};
            app.T05.RowName = {};
            app.TransformationMatrixT05Label = uilabel(app.RobotHMIUIFigure);
            app.TransformationMatrixT05Label.Text = 'Transformation Matrix (T05)';
            app.UIAxes = uiaxes(app.RobotHMIUIFigure);
            title(app.UIAxes, 'Joint 1 Angle')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Angle [°]')
            app.RightArrowSlider1 = uibutton(app.RobotHMIUIFigure, 'push');
            app.RightArrowSlider1.ButtonPushedFcn = createCallbackFcn(app, @RightArrowSlider1ButtonPushed, true);
            app.RightArrowSlider1.Text = '->';
            app.RightArrowSlider2 = uibutton(app.RobotHMIUIFigure, 'push');
            app.RightArrowSlider2.ButtonPushedFcn = createCallbackFcn(app, @RightArrowSlider2ButtonPushed, true);
            app.RightArrowSlider2.Text = '->';
            app.RightArrowSlider3 = uibutton(app.RobotHMIUIFigure, 'push');
            app.RightArrowSlider3.ButtonPushedFcn = createCallbackFcn(app, @RightArrowSlider3ButtonPushed, true);
            app.RightArrowSlider3.Text = '->';
            app.RightArrowSlider4 = uibutton(app.RobotHMIUIFigure, 'push');
            app.RightArrowSlider4.ButtonPushedFcn = createCallbackFcn(app, @RightArrowSlider4ButtonPushed, true);
            app.RightArrowSlider4.Text = '->';
            app.RightArrowSlider5 = uibutton(app.RobotHMIUIFigure, 'push');
            app.RightArrowSlider5.ButtonPushedFcn = createCallbackFcn(app, @RightArrowSlider5ButtonPushed, true);
            app.RightArrowSlider5.Text = '->';
            app.LeftArrowSlider1 = uibutton(app.RobotHMIUIFigure, 'push');
            app.LeftArrowSlider1.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowSlider1ButtonPushed, true);
            app.LeftArrowSlider1.Text = '<-';
            app.LeftArrowSlider2 = uibutton(app.RobotHMIUIFigure, 'push');
            app.LeftArrowSlider2.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowSlider2ButtonPushed, true);
            app.LeftArrowSlider2.Text = '<-';
            app.LeftArrowSlider3 = uibutton(app.RobotHMIUIFigure, 'push');
            app.LeftArrowSlider3.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowSlider3ButtonPushed, true);
            app.LeftArrowSlider3.Text = '<-';
            app.LeftArrowSlider4 = uibutton(app.RobotHMIUIFigure, 'push');
            app.LeftArrowSlider4.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowSlider4ButtonPushed, true);
            app.LeftArrowSlider4.Text = '<-';
            app.LeftArrowSlider5 = uibutton(app.RobotHMIUIFigure, 'push');
            app.LeftArrowSlider5.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowSlider5ButtonPushed, true);
            app.LeftArrowSlider5.Text = '<-';
            % Show the figure after all components are created
            app.RobotHMIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IHMRoboDidatico_V0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RobotHMIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RobotHMIUIFigure)
        end
    end
end